classdef EDDataVisualizerAndFormatConverter_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                         matlab.ui.Figure
        axes1                           matlab.ui.control.UIAxes
        uipanel6                        matlab.ui.container.Panel
        previousDP                      matlab.ui.control.Button
        nextDP                          matlab.ui.control.Button
        load                            matlab.ui.control.Button
        minInt                          matlab.ui.control.Slider
        text19                          matlab.ui.control.Label
        maxInt                          matlab.ui.control.Slider
        text21                          matlab.ui.control.Label
        numberText                      matlab.ui.control.Label
        lastDP                          matlab.ui.control.Button
        firstDP                         matlab.ui.control.Button
        selectFrameBar                  matlab.ui.control.Slider
        uipanel8                        matlab.ui.container.Panel
        initialAngle                    matlab.ui.control.EditField
        finalAngle                      matlab.ui.control.EditField
        tiltStep                        matlab.ui.control.EditField
        text7                           matlab.ui.control.Label
        text8                           matlab.ui.control.Label
        text9                           matlab.ui.control.Label
        calibrationValue                matlab.ui.control.EditField
        text12                          matlab.ui.control.Label
        selectFirstFrame                matlab.ui.control.Button
        selectLastFrame                 matlab.ui.control.Button
        firstFrameText                  matlab.ui.control.Label
        lastFrameText                   matlab.ui.control.Label
        darkCheck                       matlab.ui.control.CheckBox
        cutCheck                        matlab.ui.control.CheckBox
        centerText                      matlab.ui.control.Label
        selectCenter                    matlab.ui.control.Button
        saveProcessedData               matlab.ui.control.Button
        generateMRC                     matlab.ui.control.Button
        roiCheckOption                  matlab.ui.control.CheckBox
        AutoCheckBox                    matlab.ui.control.CheckBox
        SelectframeanglesfromatxtfileCheckBox  matlab.ui.control.CheckBox
        SubtractValuetoFramesLabel      matlab.ui.control.Label
        SubtractValuetoFramesEditField  matlab.ui.control.EditField
        CheckSaturationButton           matlab.ui.control.Button
        SatValueEditFieldLabel          matlab.ui.control.Label
        SatValueEditField               matlab.ui.control.NumericEditField
        Image                           matlab.ui.control.Image
    end

    
    methods (Access = private)
        function cutAndSquaredData(app, finalNumberOfFrames, factor, paramMsgBox)
            global fileName pathName firstFrame ycenter xcenter newPathName divisionForMRCcreation
            
            directoriTest = [pathName char(fileName(firstFrame))];
            if directoriTest(end) == '3'
                DPtest = getdm3image(directoriTest ,1);
            else
                DPtest = imread(directoriTest);
            end
            [ySize, xSize] = size(DPtest);
            downX = xSize-xcenter;
            rightY = ySize-ycenter;
            
            vectorSizes = [round(xcenter) round(ycenter) downX rightY];
            positionMinimum = find(vectorSizes == min(vectorSizes));
            finalSize = round((vectorSizes(positionMinimum(1))));
            if mod(finalSize,2) == 1
            	finalSize = finalSize-1;
            end
            
            mkdir(pathName,'CutAndSquaredData');
            newPathName = [pathName 'CutAndSquaredData\'];
            bar = waitbar(0,'Initializing ... ','Name','Storing Cut & Squared Data');
            
            directoryDP = [pathName char(fileName(firstFrame))];
            if directoryDP(end) == '3'
                DP = getdm3image(directoryDP ,1);
            else
                DP = imread(directoryDP);
            end
            directoriNou = [pathName 'CutAndSquaredData/' sprintf('%03d',firstFrame) '.tif'];
            frame = DP(round(ycenter)-finalSize+1:round(ycenter)+finalSize,round(xcenter)-finalSize+1:round(xcenter)+finalSize);
            midaFrame = size(frame);
            valueToSubtract = str2double(get(app.SubtractValuetoFramesEditField,'Value'));
            
            dividingFactor = double(ceil(max(max(frame))/65536))
            divisionForMRCcreation = ((max(max(double(frame)))./dividingFactor) > 31128);
            imwrite(uint16((double(frame)-(valueToSubtract.*ones(midaFrame(1),midaFrame(2))))./dividingFactor),directoriNou,'Compression','none');
            
            for i=1:(round(finalNumberOfFrames)-1)
            
                directoryDP = [pathName char(fileName(round(i*factor)+firstFrame))];
                if directoryDP(end) == '3'
                    DP = getdm3image(directoryDP ,1);
                else
                    DP = imread(directoryDP);
                end
            
                dpName = [sprintf('%03d',(round((i*factor)+firstFrame))) '.tif'];
                directoriNou = [pathName 'CutAndSquaredData/' dpName];
                frame = DP(round(ycenter)-finalSize+1:round(ycenter)+finalSize,round(xcenter)-finalSize+1:round(xcenter)+finalSize);
                imwrite(uint16((double(frame)-(valueToSubtract.*ones(midaFrame(1),midaFrame(2))))./dividingFactor),directoriNou,'Compression','none');
            
                percentatge = uint8(100*i/round(finalNumberOfFrames));
                waitbar(double(percentatge)/100,bar,sprintf('%d%%',percentatge));
            
            end
            close(bar)
            if ~exist('paramMsgBox','var')
                paramMsgBox = false;
            end
            if paramMsgBox == false 
                msgbox({'The square frames have been saved at the folder "CutAndSqauredData" in: ' pathName},'3D ED Data')
            end
        end
        
        function darkAndSquaredData(app, finalNumberOfFrames, factor, paramMsgBox)
            global fileName pathName firstFrame newPathName divisionForMRCcreation
            
            directoriTest = [pathName char(fileName(firstFrame))];
            if directoriTest(end) == '3'
                DPtest = getdm3image(directoriTest ,1);
            else
                DPtest = imread(directoriTest);
            end
            [ySize, xSize] = size(DPtest);
            
            if ySize > xSize
                if mod(ySize,2) == 1
                   ySize = ySize-1;
                end
                finalSize = ySize;
                extraPixels = ySize-xSize;
                eval = true;
            else
                if mod(xSize,2) == 1
                   xSize = xSize-1;
                end
                finalSize = xSize;
                extraPixels = xSize-ySize;
                eval = false;
            end
            
            template = double(zeros(finalSize,finalSize));
            Offset = uint16(round(extraPixels/2));
            
            mkdir(pathName,'DarkAndSquaredData');
            directoryDP = [pathName char(fileName(firstFrame))];
            if directoryDP(end) == '3'
                DP = getdm3image(directoryDP ,1);
            else
                DP = imread(directoryDP);
            end
            
            newDirectory = [pathName 'DarkAndSquaredData/' sprintf('%03d',firstFrame) '.tif'];
            newPathName = [pathName 'DarkAndSquaredData/'];
            valueToSubtract = str2double(get(app.SubtractValuetoFramesEditField,'Value'));
            
            if eval == true
                template(1:(ySize),Offset:(xSize+Offset-1)) = double(DP)-double(valueToSubtract.*ones(ySize,xSize));
            else
                template(Offset:(ySize+Offset-1),1:(xSize)) = double(DP)-double(valueToSubtract.*ones(ySize,xSize));
            end
            
            dividingFactor = double(ceil(max(max(template))/65536));
            divisionForMRCcreation = ((max(max(template))./dividingFactor) > 31128);
            imwrite(uint16(double(template)./dividingFactor),newDirectory,'Compression','none');
            
            bar = waitbar(0,'Initializing ... ','Name','Storing Expanded Data');
            for i=1:(round(finalNumberOfFrames)-1)
            
                directoryDP = [pathName char(fileName(round((i*factor)+firstFrame)))];
                if directoryDP(end) == '3'
                    DP = getdm3image(directoryDP ,1);
                else
                    DP = imread(directoryDP);
                end
            
                dpName = [sprintf('%03d',(round(i*factor)+firstFrame)) '.tif'];
                newDirectory = [pathName 'DarkAndSquaredData/' dpName];
            
                if eval == true
                    template(1:(ySize),Offset:(xSize+Offset-1)) = double(DP)-double(valueToSubtract.*ones(ySize,xSize));            
                else
                    template(Offset:(ySize+Offset-1),1:(xSize)) = double(DP)-double(valueToSubtract.*ones(ySize,xSize));
                end
                imwrite(uint16(double(template)./dividingFactor),newDirectory,'Compression','none');
                
                percentage = uint8(100*i/round(finalNumberOfFrames));
                waitbar(double(percentage)/100,bar,sprintf('%d%%',percentage));
            end
            close(bar)
            if ~exist('paramMsgBox','var')
                paramMsgBox = false;
            end
            if paramMsgBox == false 
                msgbox({'The dark and square frames have been saved at the folder "DarkAndSquaredData" in: ' pathName},'3D ED Data')
            end 
        end
        
        function roiCutData(app, finalNumberOfFrames, factor, paramMsgBox)
            global rectangleROI pathName newPathName fileName firstFrame divisionForMRCcreation
            
            if ~exist('paramMsgBox','var')
                paramMsgBox = false;
            end
            positionsRectangle = round(rectangleROI.Position);
            
            mkdir(pathName,'ROICutData');
            newPathName = [pathName 'ROICutData\'];
            bar = waitbar(0,'Initializing ... ','Name','Storing Cut Data');
            
            directoryDP = [pathName char(fileName(firstFrame))];
            if directoryDP(end) == '3'
                DP = getdm3image(directoryDP ,1);
            else
                DP = imread(directoryDP);
            end
            
            directoriNou = [pathName 'ROICutData/' sprintf('%03d',firstFrame) '.tif'];
            
            if mod(positionsRectangle(4),2) == 0
            	positionsRectangle(4) = positionsRectangle(4) - 1;
                positionsRectangle(3) = positionsRectangle(3) - 1;
            end
            
            frame = DP(positionsRectangle(2)+1:positionsRectangle(2)+1+positionsRectangle(4),positionsRectangle(1)+1:positionsRectangle(1)+1+positionsRectangle(3));
            midaFrame = size(frame);
            valueToSubtract = str2double(get(app.SubtractValuetoFramesEditField,'Value'));
            
            dividingFactor = double(ceil(max(max(frame))/65536));
            divisionForMRCcreation = ((max(max(double(frame)))./dividingFactor) > 31128);
            imwrite(uint16((double(frame)-double(valueToSubtract.*ones(midaFrame(1),midaFrame(2))))./dividingFactor),directoriNou,'Compression','none');
            
            for i=1:(round(finalNumberOfFrames)-1)
                
                directoryDP = [pathName char(fileName(round(i*factor)+firstFrame))];
                if directoryDP(end) == '3'
                    DP = getdm3image(directoryDP ,1);
                else
                    DP = imread(directoryDP);
                end
            
                dpName = [sprintf('%03d',((round((i*factor)+firstFrame)))) '.tif'];
                directoriNou = [pathName 'ROICutData/' dpName];
                frame = DP(positionsRectangle(2)+1:positionsRectangle(2)+1+positionsRectangle(4),positionsRectangle(1)+1:positionsRectangle(1)+1+positionsRectangle(3));
                imwrite(uint16((double(frame)-double(valueToSubtract.*ones(midaFrame(1),midaFrame(2))))./dividingFactor),directoriNou,'Compression','none');
            
                percentatge = uint8(100*i/finalNumberOfFrames);
                waitbar(double(percentatge)/100,bar,sprintf('%d%%',percentatge));
            
            end
            
            close(bar) 
            if ~exist('paramMsgBox','var')
                paramMsgBox = false;
            end
            if paramMsgBox == false 
                msgbox({'The ROI cut frames are have been saved at the folder "ROICutData" in: ' pathName},'3D ED Data')
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function EDDataVisualizerAndFormatConverter_OpeningFcn(app)
            global pathName
            set(app.text7,'Text',['Initial Angle (' char(176) ') :'])
            set(app.text8,'Text',['Final Angle (' char(176) ') :'])
            set(app.text9,'Text',['Tilt Step (' char(176) ') :'])
            set(app.text12,'Text',['Calibration (' char(197) '-1/pixel) :'])
            set(app.numberText,'Text',{'0/0' '-'})
            pathName = pwd;
            
            % Part of the code to change the icon on the window of the APP
            app.figure1.Visible = 'off'; % hide figure while Icon is still old icon
            drawnow %It seems we need to pause execution here otherwise cefWindowList won't have the UIFigure in it yet
            cefWindowList = matlab.internal.webwindowmanager.instance.windowList;
            url = matlab.ui.internal.FigureServices.getFigureURL(app.figure1);
            [idx] = arrayfun(@(ww)strcmp(url,ww.URL), cefWindowList);
            hWin = cefWindowList(idx);
            if isdeployed % Executable in stand-alone mode.
                [~, result] = system('path');
                exedir = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
                hWin.Icon = fullfile(exedir, '3DEDVC_iconForGUI.ico');
            else
                hWin.Icon = which('3DEDVC_iconForGUI.ico');
            end
            app.figure1.Visible = 'on';
        end

        % Value changed function: cutCheck
        function cutCheck_Callback(app, event)
            global rectangleROI isThereAPointROI pixelPositionROI image
            if isThereAPointROI == true
                delete(pixelPositionROI)
                isThereAPointROI = false;
            end
            set(app.centerText,'Text',sprintf('Center:   (%s,%s)', num2str(round(size(image,2)/2)), num2str(round(size(image,1))/2)))
            if get(app.cutCheck,'Value')== 1
                set(app.darkCheck,'Value',0)
                set(app.selectCenter,'enable',true)
            else
                set(app.darkCheck,'Value',1)
                set(app.selectCenter,'enable',false)
            end
            set(app.roiCheckOption,'Value',0')
            try
                delete(rectangleROI)
            catch
            end
        end

        % Value changed function: darkCheck
        function darkCheck_Callback(app, event)
            global rectangleROI isThereAPointROI pixelPositionROI image
            if isThereAPointROI == true
                delete(pixelPositionROI)
                isThereAPointROI = false;
            end
            if get(app.darkCheck,'Value') == 1
                set(app.cutCheck,'Value',0)
                set(app.selectCenter,'enable',false)
            else
                set(app.cutCheck,'Value',1)
                set(app.centerText,'Text',sprintf('Center:   (%s,%s)', num2str(round(size(image,2)/2)), num2str(round(size(image,1))/2)))
                set(app.selectCenter,'enable',true)
            end
            set(app.roiCheckOption,'Value',0')
            try
                delete(rectangleROI)
            catch
            end
        end

        % Button pushed function: firstDP
        function firstDP_Callback(app, event)
            global imagePosition totalNumber pathName DP handlesRaw minI maxI fileName
            imagePosition = 1;
            allRect = findall(app.axes1,'Type', 'Rectangle'); 
            delete(allRect);
            
            set(app.selectFrameBar,'Value',((imagePosition-1)/(totalNumber-1)));
            set(app.numberText,'Text',{['1/' num2str(totalNumber)] [char(fileName(1))]})
            directoryRef = [pathName char(fileName(imagePosition))];
            if directoryRef(end) == '3'
                DP = getdm3image(directoryRef ,1);
            else
                DP = imread(directoryRef);
            end
            set(handlesRaw,'CData',DP);
            set(app.axes1,'CLim',[minI maxI])
            set(app.nextDP,'enable','on');
            set(app.previousDP,'enable','off');
            set(app.lastDP,'enable','on');
            set(app.firstDP,'enable','off');
        end

        % Button pushed function: generateMRC
        function generateMRC_Callback(app, event)
            global lastFrame firstFrame pathName newPathName fileName ycenter xcenter divisionForMRCcreation
            iniAngleInput = str2double(get(app.initialAngle,'Value'));
            finAngleInput = str2double(get(app.finalAngle,'Value'));
            if (isnan(iniAngleInput) == false && isnan(finAngleInput) == false)

                angularRange = finAngleInput-iniAngleInput;               
                calibration = str2double(get(app.calibrationValue,'Value'));
                totalNumber = (lastFrame-firstFrame)+1;
                
                if (get(app.AutoCheckBox,'Value') == false && get(app.SelectframeanglesfromatxtfileCheckBox,'Value') == false)
                    tiltStepInput = str2double(get(app.tiltStep,'Value'));
                elseif (get(app.SelectframeanglesfromatxtfileCheckBox,'Value') == false)
                    tiltStepInput = angularRange/(totalNumber - 1);
                    set(app.tiltStep,'Value',num2str(tiltStepInput))
                end         
                
                if (get(app.SelectframeanglesfromatxtfileCheckBox,'Value') == false)
                    finalNumberOfFrames = ((angularRange/tiltStepInput)+1);
                else 
                    finalNumberOfFrames = totalNumber;
                end

                factor = totalNumber/(finalNumberOfFrames);
                
                if get(app.cutCheck,'Value') == 1
                
                    cutAndSquaredData (app, finalNumberOfFrames, factor, true );
                
                elseif get(app.darkCheck,'Value') == 1
                
                    darkAndSquaredData(app, finalNumberOfFrames, factor, true );
                
                elseif get(app.roiCheckOption,'Value') == 1
                
                    roiCutData(app, finalNumberOfFrames, factor, true);
                
                end
                
                fname_in= [newPathName sprintf('%03d',firstFrame) '.tif'];
                info=imfinfo(fname_in);
                
                dialog=inputdlg('Write the MRC Name:', '3D ED Data', [1 40], {'MRCname'});
                gen_name=[pathName char(dialog)];
                
                fileIDinfo = fopen([gen_name '_MRCFileParameters.txt'],'w');
                fprintf(fileIDinfo,'%s\t%s\n','First Selected Frame:',char(fileName(firstFrame)));
                fprintf(fileIDinfo,'%s\t%s\n','Last Selected Frame:', char(fileName(lastFrame)));
                fprintf(fileIDinfo,'%s\t%.1f\n','Initial Angle:',iniAngleInput);
                fprintf(fileIDinfo,'%s\t%.1f\n','Final Angle:',finAngleInput);
                if (get(app.SelectframeanglesfromatxtfileCheckBox,'Value') == false)
                    fprintf(fileIDinfo,'%s\t%.1f\n','Tilt Step:',tiltStepInput);
                else
                    fprintf(fileIDinfo,'%s\t%.1f\n','Tilt Step:','.txt file');
                end 
                fprintf(fileIDinfo,'%s\t%.6f\n','Calibration (A-1/pixel):',calibration);
                fprintf(fileIDinfo,'%s\t ( %.1f , %.1f )','Center (x,y):',xcenter,ycenter);
                fclose(fileIDinfo);
                
                mrc_name=[gen_name, '.mrc'];
                
                fid_mrc=fopen(mrc_name, 'w');
                Basic.numfloats=38;
                Basic.next=Basic.numfloats*4*1024;
                buff(1:Basic.next+1024)=0;
                fwrite(fid_mrc, buff, 'char');
                status = fseek(fid_mrc, Basic.next+1024, 'bof');
                
                Basic.nz=0;
                name = [newPathName sprintf('%03d',firstFrame) '.tif'];
                
                info=imfinfo(name);
                fid = fopen(name, 'r');
                if size(info.StripOffsets, 1)>1
                    data_start=info.StripOffsets(1, 1);
                else
                    data_start=info.StripOffsets;
                end
                
                status = fseek(fid, data_start(1), 'bof');
                if name(end) == '3'
                    slice = getdm3image(name ,1);
                else
                    slice = imread(name);
                end
                fclose(fid);
               
                if divisionForMRCcreation == true
                    scaleInts = 2;
                else
                    scaleInts = 1;
                end
                s=int16(rot90(slice,3)./scaleInts);
                
                fwrite(fid_mrc, s,'int16');
                
                Basic.nz=Basic.nz+1;
                
                bar = waitbar(0,'Initializing ... ','Name','Creating the MRC File');
                waitbar(double(0)/100,bar,sprintf('%d%%',0));
                for i=1:(round(finalNumberOfFrames)-1)
                
                    name = [newPathName sprintf('%03d',((round(i*factor)+firstFrame))) '.tif'];
                
                    info=imfinfo(name);
                    fid = fopen(name, 'r');
                    if size(info.StripOffsets, 1)>1
                        data_start=info.StripOffsets(1, 1);
                    else
                        data_start=info.StripOffsets;
                    end
                
                    status = fseek(fid, data_start(1), 'bof');
                    if name(end) == '3'
                        slice = getdm3image(name ,1);
                    else
                        slice = imread(name);
                    end
                    fclose(fid);
                    
                    s=int16(rot90(slice,3)./scaleInts);
                    fwrite(fid_mrc, s,'int16');
                    Basic.nz=Basic.nz+1;
                    percentage = uint8(100*i/round(finalNumberOfFrames));
                    waitbar(double(percentage)/100,bar,sprintf('%d%%',percentage));
                
                end
                close(bar)
                status = fseek(fid_mrc, 0, 'bof');
                
                
                % MRC header definition: BASIC
                
                Basic.nx=info.Width;
                fwrite(fid_mrc, Basic.nx, 'int');   % integer 4-byte
                Basic.ny=info.Height;
                fwrite(fid_mrc, Basic.ny, 'int');   % integer 4-byte
                fwrite(fid_mrc, Basic.nz, 'int');   % integer 4-byte
                Basic.mode=1;
                fwrite(fid_mrc, Basic.mode, 'int');   % integer 4-byte
                Basic.nxstart=0;
                fwrite(fid_mrc, Basic.nxstart, 'int');   % integer 4-byte
                Basic.nystart=0;
                fwrite(fid_mrc, Basic.nystart, 'int');   % integer 4-byte
                Basic.nzstart=0;
                fwrite(fid_mrc, Basic.nzstart, 'int');   % integer 4-byte
                Basic.mx=info.Width;
                fwrite(fid_mrc, Basic.mx, 'int');   % integer 4-byte
                Basic.my=info.Height;
                fwrite(fid_mrc, Basic.my, 'int');   % integer 4-byte
                Basic.mz=Basic.nz;
                fwrite(fid_mrc, Basic.mz, 'int');   % integer 4-byte
                Basic.xlen=info.Width;
                fwrite(fid_mrc, Basic.xlen, 'float');   % float 4-byte
                Basic.ylen=info.Height;
                fwrite(fid_mrc, Basic.ylen, 'float');   % float 4-byte
                Basic.zlen=Basic.nz;
                fwrite(fid_mrc, Basic.zlen, 'float');   % float 4-byte
                Basic.alpha=90;
                fwrite(fid_mrc, Basic.alpha, 'float');   % float 4-byte
                Basic.beta=90;
                fwrite(fid_mrc, Basic.beta, 'float');   % float 4-byte
                Basic.gamma=90;
                fwrite(fid_mrc, Basic.gamma, 'float');   % float 4-byte
                Basic.mapc=1;
                fwrite(fid_mrc, Basic.mapc, 'int');   % integer 4-byte
                Basic.mapr=2;
                fwrite(fid_mrc, Basic.mapr, 'int');   % integer 4-byte
                Basic.maps=3;
                fwrite(fid_mrc, Basic.maps, 'int');   % integer 4-byte
                Basic.amin=min(min(slice));
                fwrite(fid_mrc, Basic.amin, 'float');   % float 4-byte
                Basic.amax=max(max(slice));
                fwrite(fid_mrc, Basic.amax, 'float');   % float 4-byte
                Basic.amean=mean(mean(slice));
                fwrite(fid_mrc, Basic.amean, 'float');   % float 4-byte
                Basic.ispg=0;
                fwrite(fid_mrc, Basic.ispg, 'short');   % integer 2-byte
                Basic.nsymbt=0;
                fwrite(fid_mrc, Basic.nsymbt, 'short');   % integer 2-byte
                fwrite(fid_mrc, Basic.next, 'int');   % integer 4-byte
                Basic.dvid=69;
                fwrite(fid_mrc, Basic.dvid, 'short');   % integer 2-byte
                Basic.extra(1:30)=0;
                fwrite(fid_mrc, Basic.extra, 'char');   % extra 30 bytes data (not used)
                Basic.numintegers=0;
                fwrite(fid_mrc, Basic.numintegers, 'short');   % integer 2-byte
                fwrite(fid_mrc, Basic.numfloats, 'short');   % integer 2-byte
                Basic.sub=0;
                fwrite(fid_mrc, Basic.sub, 'short');   % integer 2-byte
                Basic.zfac=0;
                fwrite(fid_mrc, Basic.zfac, 'short');   % integer 2-byte
                Basic.min2=0;
                fwrite(fid_mrc, Basic.min2, 'float');   % float 4-byte
                Basic.max2=0;
                fwrite(fid_mrc, Basic.max2, 'float');   % float 4-byte
                Basic.min3=0;
                fwrite(fid_mrc, Basic.min3, 'float');   % float 4-byte
                Basic.max3=0;
                fwrite(fid_mrc, Basic.max3, 'float');   % float 4-byte
                Basic.min4=0;
                fwrite(fid_mrc, Basic.min4, 'float');   % float 4-byte
                Basic.max4=0;
                fwrite(fid_mrc, Basic.max4, 'float');   % float 4-byte
                Basic.idtype=1205;
                fwrite(fid_mrc, Basic.idtype, 'short');   % integer 2-byte
                Basic.lens=0;
                fwrite(fid_mrc, Basic.lens, 'short');   % integer 2-byte
                Basic.nd1=0;
                fwrite(fid_mrc, Basic.nd1, 'short');   % integer 2-byte
                Basic.nd2=0;
                fwrite(fid_mrc, Basic.nd2, 'short');   % integer 2-byte
                Basic.vd1=0;
                fwrite(fid_mrc, Basic.vd1, 'short');   % integer 2-byte
                Basic.vd2=0;
                fwrite(fid_mrc, Basic.vd2, 'short');   % integer 2-byte
                Basic.tiltangles(1:9)=0;
                
                for i=1:9
                    fwrite(fid_mrc, Basic.tiltangles(i), 'float');   %float 4-byte
                end
                
                Basic.zorg=0;
                fwrite(fid_mrc, Basic.zorg, 'float');   % float 4-byte
                Basic.xorg=0;
                fwrite(fid_mrc, Basic.xorg, 'float');   % float 4-byte
                Basic.yorg=0;
                fwrite(fid_mrc, Basic.yorg, 'float');   % float 4-byte
                Basic.nlabl=1;
                fwrite(fid_mrc, Basic.nlabl, 'int');   % integer 4-byte
                Basic.data='MRC file created';
                fwrite(fid_mrc, Basic.data, 'char');   % 10 text labels with 80 characters
                
                
                % ************* Extended ********************
                
                if (get(app.SelectframeanglesfromatxtfileCheckBox,'Value') == true)
                    [filenameTiltAngle, pathnameTiltAngle] = uigetfile({'*.txt'}, 'Select your .txt file with defined angles');
                    figure(app.figure1);
                    fileIDo = fopen([pathnameTiltAngle filenameTiltAngle],'r');
                    angles = textscan(fileIDo,'%f');
                    fclose(fileIDo);
                    anglesVector = angles{1};
                    for i=1:Basic.nz
                        Extended.a_tilt(i)=(anglesVector(i));
                    end 
                else
                    for i=1:Basic.nz
                        Extended.a_tilt(i)=iniAngleInput+(i-1)*tiltStepInput;
                    end    
                end
                
                Extended.b_tilt=0;
                Extended.x=0;
                Extended.y=0;
                Extended.z=0;
                Extended.x_shift=0;
                Extended.y_shift=0;
                Extended.defocus=0;
                Extended.exposure=0;
                Extended.mean_intensity=Basic.amean;
                Extended.tilt_axis=0;
                Extended.pixel_size = 1e10*calibration;
                answer = inputdlg({'Camera Length (mm)', 'High Tension (kV)'}, '3D ED Data', [1 40], {'150','200'});
                Extended.magnification = str2double(answer{1});
                Extended.Microscope_type = 99;
                Extended.Gun_type = 0;
                Extended.Lens_type=0;
                Extended.D_number_of_microscope=0;
                Extended.High_tension=str2double(answer{2});
                Extended.Diffraction_lens_value_in_percent=35; %Default value
                Extended.MTF=0;
                Extended.Starting_Df=0;
                Extended.Focus_step=0;
                Extended.DAC_setting=0;
                Extended.Spherical_aberration=0;
                Extended.Semi_convergence=0;
                Extended.Info_limit=0;
                Extended.Number_of_images=Basic.nz;
                for i=1:Basic.nz
                    Extended.Image_number_in_series(i)=i;
                end
                Extended.Coma_3_X=0;
                Extended.Coma_3_Y=0;
                Extended.Astigmatism_2_X=0;
                Extended.Astigmatism_2_Y=0;
                Extended.Astigmatism_3_X=0;
                Extended.Astigmatism_3_Y=0;
                Extended.Camera_type_number=0;
                Extended.Camera_position=0;
                Extended.Spherical_Aberration_4=0;
                Extended.Star_Aberration_4_X=0;
                Extended.Star_Aberration_4_Y=0;
                Extended.Astigmatism_4_X=0;
                Extended.Astigmatism_4_Y=0;
                Extended.Coma_5_X=0;
                Extended.Coma_5_Y=0;
                Extended.Three_Lobe_5_X=0;
                Extended.Three_Lobe_5_Y=0;
                Extended.Astigmatism_5_X=0;
                Extended.Astigmatism_5_Y=0;
                Extended.Spherical_Aberration_6=0;
                Extended.Star_Aberration_6_X=0;
                Extended.Star_Aberration_6_Y=0;
                Extended.Rosette_Aberration_6_X=0;
                Extended.Rosette_Aberration_6_Y=0;
                Extended.Astigmatism_6_X=0;
                Extended.Astigmatism_6_Y=0;
                Extended.SI_Units=1;
                
                %%%%%%% WRITING EXTENDED HEADER
                
                for i=1:Basic.nz
                
                    status = fseek(fid_mrc, 1024+Basic.numfloats*4*(i-1), 'bof');
                    fwrite(fid_mrc, Extended.a_tilt(i), 'float');   % float 4-byte
                    fwrite(fid_mrc, Extended.b_tilt, 'float');   % float 4-byte
                    fwrite(fid_mrc, Extended.x, 'float');   % float 4-byte
                    fwrite(fid_mrc, Extended.y, 'float');   % float 4-byte
                    fwrite(fid_mrc, Extended.z, 'float');   % float 4-byte
                    fwrite(fid_mrc, Extended.x_shift, 'float');   % float 4-byte
                    fwrite(fid_mrc, Extended.y_shift, 'float');   % float 4-byte
                    fwrite(fid_mrc, Extended.defocus, 'float');   % float 4-byte
                    fwrite(fid_mrc, Extended.exposure, 'float');   % float 4-byte
                    fwrite(fid_mrc, Extended.mean_intensity, 'float');   % float 4-byte
                    fwrite(fid_mrc, Extended.tilt_axis, 'float');   % float 4-byte
                    fwrite(fid_mrc, Extended.pixel_size, 'float');   % float 4-byte
                    fwrite(fid_mrc, Extended.magnification, 'float');   % float 4-byte
                    fwrite(fid_mrc, Extended.Microscope_type, 'float');   % float 4-byte
                    fwrite(fid_mrc, Extended.Gun_type, 'float');   % float 4-byte
                    fwrite(fid_mrc, Extended.Lens_type, 'float');   % float 4-byte
                    fwrite(fid_mrc, Extended.D_number_of_microscope, 'float');   % float 4-byte
                    fwrite(fid_mrc, Extended.High_tension, 'float');   % float 4-byte
                    fwrite(fid_mrc, Extended.Diffraction_lens_value_in_percent, 'float');   % float 4-byte
                    fwrite(fid_mrc, Extended.MTF, 'float');   % float 4-byte
                    fwrite(fid_mrc, Extended.Starting_Df, 'float');   % float 4-byte
                    fwrite(fid_mrc, Extended.Focus_step, 'float');   % float 4-byte
                    fwrite(fid_mrc, Extended.DAC_setting, 'float');   % float 4-byte
                    fwrite(fid_mrc, Extended.Spherical_aberration, 'float');   % float 4-byte
                    fwrite(fid_mrc, Extended.Semi_convergence, 'float');   % float 4-byte
                    fwrite(fid_mrc, Extended.Info_limit, 'float');   % float 4-byte
                    fwrite(fid_mrc, Extended.Number_of_images, 'float');   % float 4-byte
                    fwrite(fid_mrc, Extended.Image_number_in_series(i), 'float');   % float 4-byte
                    fwrite(fid_mrc, Extended.Coma_3_X, 'float');   % float 4-byte
                    fwrite(fid_mrc, Extended.Coma_3_Y, 'float');   % float 4-byte
                    fwrite(fid_mrc, Extended.Astigmatism_2_X, 'float');   % float 4-byte
                    fwrite(fid_mrc, Extended.Astigmatism_2_Y, 'float');   % float 4-byte
                    fwrite(fid_mrc, Extended.Astigmatism_3_X, 'float');   % float 4-byte
                    fwrite(fid_mrc, Extended.Astigmatism_3_Y, 'float');   % float 4-byte
                    fwrite(fid_mrc, Extended.Camera_type_number, 'float');   % float 4-byte
                    fwrite(fid_mrc, Extended.Camera_position, 'float');   % float 4-byte
                    fwrite(fid_mrc, Extended.Spherical_Aberration_4, 'float');   % float 4-byte
                    fwrite(fid_mrc, Extended.Star_Aberration_4_X, 'float');   % float 4-byte
                    fwrite(fid_mrc, Extended.Star_Aberration_4_Y, 'float');   % float 4-byte
                    fwrite(fid_mrc, Extended.Astigmatism_4_X, 'float');   % float 4-byte
                    fwrite(fid_mrc, Extended.Astigmatism_4_Y, 'float');   % float 4-byte
                    fwrite(fid_mrc, Extended.Coma_5_X, 'float');   % float 4-byte
                    fwrite(fid_mrc, Extended.Coma_5_Y, 'float');   % float 4-byte
                    fwrite(fid_mrc, Extended.Three_Lobe_5_X, 'float');   % float 4-byte
                    fwrite(fid_mrc, Extended.Three_Lobe_5_Y, 'float');   % float 4-byte
                    fwrite(fid_mrc, Extended.Astigmatism_5_X, 'float');   % float 4-byte
                    fwrite(fid_mrc, Extended.Astigmatism_5_Y, 'float');   % float 4-byte
                    fwrite(fid_mrc, Extended.Spherical_Aberration_6, 'float');   % float 4-byte
                    fwrite(fid_mrc, Extended.Star_Aberration_6_X, 'float');   % float 4-byte
                    fwrite(fid_mrc, Extended.Star_Aberration_6_Y, 'float');   % float 4-byte
                    fwrite(fid_mrc, Extended.Rosette_Aberration_6_X, 'float');   % float 4-byte
                    fwrite(fid_mrc, Extended.Rosette_Aberration_6_Y, 'float');   % float 4-byte
                    fwrite(fid_mrc, Extended.Astigmatism_6_X, 'float');   % float 4-byte
                    fwrite(fid_mrc, Extended.Astigmatism_6_Y, 'float');   % float 4-byte
                    fwrite(fid_mrc, Extended.SI_Units, 'float');   % float 4-byte
                
                end
                
                fclose(fid_mrc);
                
                msgbox({'The MRC file has been successfully created and it is stored at:' pathName},'3D ED Data')
            else
                warndlg('Fill the Initial and Final Angle before generating the MRC', '3D ED Data')
            end    
        end

        % Button pushed function: lastDP
        function lastDP_Callback(app, event)
            global imagePosition totalNumber pathName fileName DP handlesRaw minI maxI 
            imagePosition = totalNumber;
            allRect = findall(app.axes1,'Type', 'Rectangle'); 
            delete(allRect);
            
            set(app.selectFrameBar,'Value',((imagePosition-1)/(totalNumber-1)));
            set(app.numberText,'Text',{[num2str(imagePosition) '/' num2str(totalNumber)] [char(fileName(imagePosition))]})
            directoryRef = [pathName char(fileName(imagePosition))];
            if directoryRef(end) == '3'
                DP = getdm3image(directoryRef ,1);
            else
                DP = imread(directoryRef);
            end
            set(handlesRaw,'CData',DP);
            set(app.axes1,'CLim',[minI maxI])
            set(app.nextDP,'enable','off');
            set(app.previousDP,'enable','on');
            set(app.firstDP,'enable','on');
            set(app.lastDP,'enable','off');
        end

        % Button pushed function: load
        function load_Callback(app, event)
            global fileName pathName totalNumber image imagePosition XcorrEval loadFileName maximI minimI minI maxI firstFrame lastFrame handlesRaw xcenter ycenter
            XcorrEval = false;
            [fileName, pathName] = uigetfile('*.*', 'Select your Images', 'MultiSelect', 'on',pathName);
            figure(app.figure1);
            if isequal(fileName,0) || isequal(pathName,0) 
                pathName = pwd;
                return; 
            end
            if length(fileName) > 1 && iscell(fileName) == true
                loadFileName = fileName;
                totalNumber = length(fileName);
                
                directoryRef = [pathName char(fileName(1))];
                imagePosition = 1;
                firstFrame = 1;
                lastFrame = totalNumber;
                if directoryRef(end) == '3'
                    image = getdm3image(directoryRef ,1);  
                else
                    image = imread(directoryRef);
                end
                
                set(app.minInt,'Value',double(0))
                set(app.maxInt,'Value',double(1))
                hold(app.axes1,'off')
                
                % Histogram Analysis
                [val, edges] = histcounts(image,32768);
                numCounts = val(1);
                l = 2;
                imgSize = size(image);
                while ( numCounts/(imgSize(1)*imgSize(2)) < 0.001)
                    numCounts = numCounts + val(l);
                    l=l+1;
                end
                minI = double(edges(l));
                k = 2;
                while ( numCounts/(imgSize(1)*imgSize(2)) < 0.999)
                    numCounts = numCounts + val(k);
                    k=k+1;
                end
                maxI = double(edges(k));
                
                minimI = minI;
                maximI = maxI;
                set(app.minInt,'Value',0.0)
                set(app.maxInt,'Value',1.0)
                
                handlesRaw = imshow(image,[minI, maxI],'Parent',app.axes1);
                app.axes1.Units = 'Pixels';
                app.axes1.XLim = [0 handlesRaw.XData(2)];
                app.axes1.YLim = [0 handlesRaw.YData(2)];
                set(app.axes1,'BackgroundColor',[0.25, 0.25, 0.25])
                %axtoolbar(app.axes1,{'zoomin','zoomout','restoreview'})
                app.axes1.Toolbar.Visible = 'off'; 
                hold(app.axes1,'on')
                
                set(app.maxInt,'enable','on')
                set(app.minInt,'enable','on')
                set(app.nextDP,'enable','on')
                set(app.selectFrameBar,'enable','on')
                set(app.selectFrameBar,'Value',0.0);
                set(app.previousDP,'enable','off')
                set(app.numberText,'Text',{['1/' num2str(totalNumber)] [char(fileName(1))]})
                set(app.numberText,'visible','on')
                set(app.lastDP,'enable','on');
                set(app.firstFrameText,'Text',sprintf('1st Frame: %s ',char(fileName(1))))
                set(app.lastFrameText,'Text',sprintf('Last Frame: %s ',char(fileName(end))))
                set(app.cutCheck,'Value',true)
                set(app.darkCheck,'Value',false)
                set(app.roiCheckOption,'Value',false)
                [ysize, xsize] = size(image);
                xcenter = round(xsize/2);
                ycenter = round(ysize/2);
                set(app.centerText,'Text',sprintf('Center:   (%s,%s)', num2str(xcenter), num2str(ycenter)))
                set(app.selectFirstFrame,'Enable',true)
                set(app.selectLastFrame,'Enable',true)
                set(app.AutoCheckBox,'Enable',true)
                set(app.AutoCheckBox,'Value',0)
                set(app.tiltStep, 'Value','')
                set(app.cutCheck,'Enable',true)
                set(app.selectCenter,'Enable',true)
                set(app.roiCheckOption,'Enable',true)
                set(app.CheckSaturationButton,'Enable',true)
                if imgSize(1) ~= imgSize(2)
                    set(app.darkCheck,'Enable',true)
                end
                set(app.saveProcessedData,'Enable',true)
                set(app.generateMRC,'Enable',true)
                disableDefaultInteractivity(app.axes1)
            else
                pathName = pwd;
                warndlg('Single frames or stack files are not supported.','Load 3D ED Data')
            end
        end

        % Value changed function: maxInt
        function maxInt_Callback(app, event)
            global minimI maximI minI maxI
            currentValue = get(app.maxInt,'Value');
            maxI = double(currentValue*(maximI-minimI) + minimI);
            if minI >= maxI
                maxI = maximI;
            end
            set(app.axes1,'CLim',[minI maxI])
        end

        % Value changed function: minInt
        function minInt_Callback(app, event)
            global minimI maximI minI maxI
            currentValue = get(app.minInt,'Value');
            minI = double(currentValue*(maximI-minimI) + minimI);
            if minI >= maxI
                minI = minimI;
            end
            set(app.axes1,'CLim',[minI maxI])
        end

        % Button pushed function: nextDP
        function nextDP_Callback(app, event)
            global imagePosition pathName fileName totalNumber handlesRaw maxI minI
            imagePosition = imagePosition + 1;
            allRect = findall(app.axes1,'Type', 'Rectangle'); 
            delete(allRect);
            if (imagePosition <= totalNumber)
                set(app.selectFrameBar,'Value',((imagePosition-1)/(totalNumber-1)));
                set(app.numberText,'Text',{[ num2str(imagePosition) '/' num2str(totalNumber)] [char(fileName(imagePosition))]})
                                
                directoryRef = [pathName char(fileName(imagePosition))];
                if directoryRef(end) == '3'
                    DP = getdm3image(directoryRef ,1);
                else
                    DP = imread(directoryRef);
                end
                set(handlesRaw,'CData',DP);                
               
                set(app.axes1,'CLim',[minI maxI])
                if imagePosition == totalNumber
                    set(app.nextDP,'enable','off')
                    set(app.lastDP,'enable','off')
                else
                    set(app.lastDP,'enable','on')
                    set(app.nextDP,'enable','on')
                end
                set(app.firstDP,'enable','on')
                set(app.previousDP,'enable','on')
            else 
                imagePosition = imagePosition - 1;
                set(app.nextDP,'enable','off')
                set(app.lastDP,'enable','off')
            end
        end

        % Button pushed function: previousDP
        function previousDP_Callback(app, event)
            global imagePosition pathName fileName totalNumber handlesRaw minI maxI
            imagePosition = imagePosition - 1;
            allRect = findall(app.axes1,'Type', 'Rectangle'); 
            delete(allRect);
            if (imagePosition >= 1)
                set(app.selectFrameBar,'Value',((imagePosition-1)/(totalNumber-1)));
                set(app.numberText,'Text',{[num2str(imagePosition) '/' num2str(totalNumber)] [char(fileName(imagePosition))]})
                directoryRef = [pathName char(fileName(imagePosition))];
                if directoryRef(end) == '3'
                    DP = getdm3image(directoryRef ,1);
                else
                    DP = imread(directoryRef);
                end
                set(handlesRaw,'CData',DP);
                set(app.axes1,'CLim',[minI maxI])
                if imagePosition == 1
                    set(app.firstDP,'enable','off')
                    set(app.previousDP,'enable','off')
                else
                    set(app.firstDP,'enable','on')
                    set(app.previousDP,'enable','on')
                end
                set(app.lastDP,'enable','on')
                set(app.nextDP,'enable','on')
            else
                imagePosition = imagePosition + 1;
                set(app.firstDP,'enable','off')
                set(app.previousDP,'enable','off')
            end
        end

        % Value changed function: roiCheckOption
        function roiCheckOption_Callback(app, event)
            global rectangleROI isThereAPointROI pixelPositionROI image
            if isThereAPointROI == true
                delete(pixelPositionROI)
                isThereAPointROI = false;
            end
            if get(app.roiCheckOption,'Value') == 1
                set(app.cutCheck,'Value',0)
                set(app.darkCheck,'Value',0)
                set(app.selectCenter,'enable',false)
                rectangleROI = drawrectangle(app.axes1,'Position',[200,200,200,200],'FixedAspectRatio',true,'FaceAlpha',0);
            else
                set(app.cutCheck,'Value',1)
                set(app.centerText,'Text',sprintf('Center:   (%s,%s)', num2str(round(size(image,2)/2)), num2str(round(size(image,1))/2)))
                set(app.selectCenter,'enable',true)
                set(app.darkCheck,'Value',0)
                try
                    delete(rectangleROI)
                catch
                end
            end
        end

        % Button pushed function: saveProcessedData
        function saveProcessedData_Callback(app, event)
            global firstFrame lastFrame
            
            format long
            iniAngleInput = str2double(get(app.initialAngle,'Value'));
            finAngleInput = str2double(get(app.finalAngle,'Value'));
            tiltStepInput = str2double(get(app.tiltStep,'Value'));
            
            if (isnan(iniAngleInput) || isnan(finAngleInput) || isnan(tiltStepInput))
                warndlg('Fill Initial Angle, Final Angle & Tilt Step parameters to store the processed frames!','3D ED Data')
            else
                angularRange = finAngleInput-iniAngleInput;
                totalNumber = (lastFrame-firstFrame)+1;
                finalNumberOfFrames = ((angularRange/tiltStepInput)+1);
                factor = totalNumber/(finalNumberOfFrames)
                
                if get(app.cutCheck,'Value') == 1
            
                    cutAndSquaredData (app, finalNumberOfFrames, factor );
            
                elseif get(app.darkCheck,'Value') == 1
            
                    darkAndSquaredData(app, finalNumberOfFrames, factor );
            
                elseif get(app.roiCheckOption,'Value') == 1
            
                    roiCutData(app, finalNumberOfFrames, factor);
            
                end  
            end
        end

        % Button pushed function: selectCenter
        function selectCenter_Callback(app, event)
            global ycenter xcenter pixelPositionROI isThereAPointROI
            if isThereAPointROI == true
                delete(pixelPositionROI)
            end
            pixelPositionROI = drawcrosshair(app.axes1);
            xcenter = pixelPositionROI.Position(1);
            ycenter = pixelPositionROI.Position(2);
            set(app.centerText,'Text',sprintf('Center:   (%s,%s)', num2str(round(xcenter)), num2str(round(ycenter))))
            isThereAPointROI = true;
        end

        % Button pushed function: selectFirstFrame
        function selectFirstFrame_Callback(app, event)
            global imagePosition fileName firstFrame
            set(app.firstFrameText,'Text',sprintf('1st Frame: %s ',char(fileName(imagePosition))))
            firstFrame = imagePosition;
        end

        % Value changed function: selectFrameBar
        function selectFrameBar_Callback(app, event)
            global fileName pathName totalNumber imagePosition handlesRaw minI maxI
            allRect = findall(app.axes1,'Type', 'Rectangle'); 
            delete(allRect);
            currentFrame = round(get(app.selectFrameBar,'Value')*(totalNumber-1))+1;
            imagePosition = currentFrame;
            set(app.numberText,'Text',{[num2str(imagePosition) '/' num2str(totalNumber)] [char(fileName(imagePosition))]})
            directoryRef = [pathName char(fileName(imagePosition))];
            if directoryRef(end) == '3'
                DP = getdm3image(directoryRef ,1);
            else
                DP = imread(directoryRef);
            end
            set(handlesRaw,'CData',DP);
            set(app.axes1,'CLim',[minI maxI])
            if imagePosition == 1
                set(app.firstDP,'enable','off')
                set(app.previousDP,'enable','off')
            else
                set(app.firstDP,'enable','on')
                set(app.previousDP,'enable','on')
            end
            if imagePosition == totalNumber
                set(app.nextDP,'enable','off')
                set(app.lastDP,'enable','off')
            else
                set(app.lastDP,'enable','on')
                set(app.nextDP,'enable','on')
            end
        end

        % Button pushed function: selectLastFrame
        function selectLastFrame_Callback(app, event)
            global imagePosition fileName lastFrame
            set(app.lastFrameText,'Text',sprintf('Last Frame: %s ',char(fileName(imagePosition))))
            lastFrame = imagePosition;
        end

        % Value changed function: AutoCheckBox
        function AutoCheckBoxFunction(app, event)
            global firstFrame lastFrame
            value = app.AutoCheckBox.Value;
            if (value == true)
                iniAngleInput = str2double(get(app.initialAngle,'Value'));
                finAngleInput = str2double(get(app.finalAngle,'Value'));
                if (isnan(iniAngleInput) == false && isnan(finAngleInput) == false)
                    set(app.tiltStep,'Value',num2str((finAngleInput-iniAngleInput)/(lastFrame-firstFrame)))
                else
                    warndlg('Fill the Initial and Final Angle before generating the MRC', '3D ED Data')
                    set(app.AutoCheckBox,'Value',false)
                end
            end             
        end

        % Value changed function: 
        % SelectframeanglesfromatxtfileCheckBox
        function SelectframeanglesfromatxtfileCheckBoxFunction(app, event)
            value = app.SelectframeanglesfromatxtfileCheckBox.Value;
            if (value == true)
                set(app.AutoCheckBox,'Value',false)
                set(app.AutoCheckBox,'Enable',false)
                set(app.tiltStep,'Enable',false)
                set(app.initialAngle,'Enable',false)
                set(app.finalAngle,'Enable',false)
            else
                set(app.AutoCheckBox,'Enable',true)
                set(app.tiltStep,'Enable',true)
                set(app.initialAngle,'Enable',true)
                set(app.finalAngle,'Enable',true)                
            end
        end

        % Button pushed function: CheckSaturationButton
        function CheckSatuFcn(app, event)
            global handlesRaw
            allRect = findall(app.axes1,'Type', 'Rectangle'); 
            delete(allRect);
            saturationValue = get(app.SatValueEditField,'Value');
            DP = get(handlesRaw,'CData');
            lowerBoundary = find(DP >= saturationValue*0.95);
            [row,col] = ind2sub(size(DP),lowerBoundary);
            for i=1:length(lowerBoundary)
                rectangle(app.axes1,'Position',[col(i)-8 row(i)-8 16 16],'Curvature',[1 1],'EdgeColor','r')
            end      
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = '3D ED Data Visualizer & Format Converter';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.XLim = [0 1];
            app.axes1.YLim = [0 1];
            app.axes1.ZLim = [0 1];
            app.axes1.CLim = [0 1];
            app.axes1.ALim = [0 1];
            app.axes1.XTick = [];
            app.axes1.YTick = [];
            app.axes1.ZTick = [];
            app.axes1.Visible = 'off';
            app.uipanel6 = uipanel(app.figure1);
            app.uipanel6.Title = 'Load & Visualize 3D ED Data';
            app.previousDP = uibutton(app.uipanel6, 'push');
            app.previousDP.ButtonPushedFcn = createCallbackFcn(app, @previousDP_Callback, true);
            app.previousDP.Enable = 'off';
            app.previousDP.Text = '<';
            app.nextDP = uibutton(app.uipanel6, 'push');
            app.nextDP.ButtonPushedFcn = createCallbackFcn(app, @nextDP_Callback, true);
            app.nextDP.Enable = 'off';
            app.nextDP.Text = '>';
            app.load = uibutton(app.uipanel6, 'push');
            app.load.ButtonPushedFcn = createCallbackFcn(app, @load_Callback, true);
            app.load.Text = 'Load 3D ED Data';
            app.minInt = uislider(app.uipanel6);
            app.minInt.Limits = [0 1];
            app.minInt.MajorTicks = [];
            app.minInt.ValueChangedFcn = createCallbackFcn(app, @minInt_Callback, true);
            app.minInt.MinorTicks = [];
            app.minInt.Enable = 'off';
            app.text19 = uilabel(app.uipanel6);
            app.text19.Text = 'Min. Intensity Displayed';
            app.maxInt = uislider(app.uipanel6);
            app.maxInt.Limits = [0 1];
            app.maxInt.MajorTicks = [];
            app.maxInt.ValueChangedFcn = createCallbackFcn(app, @maxInt_Callback, true);
            app.maxInt.MinorTicks = [];
            app.maxInt.Enable = 'off';
            app.maxInt.Value = 1;
            app.text21 = uilabel(app.uipanel6);
            app.text21.Text = 'Max. Intensity Displayed';
            app.numberText = uilabel(app.uipanel6);
            app.numberText.Text = '0/0';
            app.lastDP = uibutton(app.uipanel6, 'push');
            app.lastDP.ButtonPushedFcn = createCallbackFcn(app, @lastDP_Callback, true);
            app.lastDP.Enable = 'off';
            app.lastDP.Text = '>>';
            app.firstDP = uibutton(app.uipanel6, 'push');
            app.firstDP.ButtonPushedFcn = createCallbackFcn(app, @firstDP_Callback, true);
            app.firstDP.Enable = 'off';
            app.firstDP.Text = '<<';
            app.selectFrameBar = uislider(app.uipanel6);
            app.selectFrameBar.Limits = [0 1];
            app.selectFrameBar.MajorTicks = [];
            app.selectFrameBar.ValueChangedFcn = createCallbackFcn(app, @selectFrameBar_Callback, true);
            app.selectFrameBar.MinorTicks = [];
            app.selectFrameBar.Enable = 'off';
            app.uipanel8 = uipanel(app.figure1);
            app.uipanel8.Title = 'Convert 3D ED Data';
            app.initialAngle = uieditfield(app.uipanel8, 'text');
            app.finalAngle = uieditfield(app.uipanel8, 'text');
            app.tiltStep = uieditfield(app.uipanel8, 'text');
            app.text7 = uilabel(app.uipanel8);
            app.text7.Text = 'Initial Angle (º):';
            app.text8 = uilabel(app.uipanel8);
            app.text8.Text = 'Final Angle (º):';
            app.text9 = uilabel(app.uipanel8);
            app.text9.Text = 'Tilt step (º):';
            app.calibrationValue = uieditfield(app.uipanel8, 'text');
            app.calibrationValue.Value = '0.001';
            app.text12 = uilabel(app.uipanel8);
            app.text12.Text = 'Calibration (A-1/pixel):';
            app.selectFirstFrame = uibutton(app.uipanel8, 'push');
            app.selectFirstFrame.ButtonPushedFcn = createCallbackFcn(app, @selectFirstFrame_Callback, true);
            app.selectFirstFrame.Enable = 'off';
            app.selectFirstFrame.Text = 'Select First Frame';
            app.selectLastFrame = uibutton(app.uipanel8, 'push');
            app.selectLastFrame.ButtonPushedFcn = createCallbackFcn(app, @selectLastFrame_Callback, true);
            app.selectLastFrame.Enable = 'off';
            app.selectLastFrame.Text = 'Select Last Frame';
            app.firstFrameText = uilabel(app.uipanel8);
            app.firstFrameText.Text = '1st Frame:     -';
            app.lastFrameText = uilabel(app.uipanel8);
            app.lastFrameText.Text = 'Last Frame:     -';
            app.darkCheck = uicheckbox(app.uipanel8);
            app.darkCheck.ValueChangedFcn = createCallbackFcn(app, @darkCheck_Callback, true);
            app.darkCheck.Enable = 'off';
            app.darkCheck.Text = 'Add Dark Pixels for Square Frames';
            app.cutCheck = uicheckbox(app.uipanel8);
            app.cutCheck.ValueChangedFcn = createCallbackFcn(app, @cutCheck_Callback, true);
            app.cutCheck.Enable = 'off';
            app.cutCheck.Text = 'Cut Square Frames |';
            app.cutCheck.Value = true;
            app.centerText = uilabel(app.uipanel8);
            app.centerText.Text = 'Centre: (-,-)';
            app.selectCenter = uibutton(app.uipanel8, 'push');
            app.selectCenter.ButtonPushedFcn = createCallbackFcn(app, @selectCenter_Callback, true);
            app.selectCenter.Enable = 'off';
            app.selectCenter.Text = 'Select Square Centre';
            app.saveProcessedData = uibutton(app.uipanel8, 'push');
            app.saveProcessedData.ButtonPushedFcn = createCallbackFcn(app, @saveProcessedData_Callback, true);
            app.saveProcessedData.Enable = 'off';
            app.saveProcessedData.Text = 'Save Processed Frames';
            app.generateMRC = uibutton(app.uipanel8, 'push');
            app.generateMRC.ButtonPushedFcn = createCallbackFcn(app, @generateMRC_Callback, true);
            app.generateMRC.Enable = 'off';
            app.generateMRC.Text = 'Create MRC File';
            app.roiCheckOption = uicheckbox(app.uipanel8);
            app.roiCheckOption.ValueChangedFcn = createCallbackFcn(app, @roiCheckOption_Callback, true);
            app.roiCheckOption.Enable = 'off';
            app.roiCheckOption.Text = 'Cut Frames by ROI ';
            app.AutoCheckBox = uicheckbox(app.uipanel8);
            app.AutoCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoCheckBoxFunction, true);
            app.AutoCheckBox.Enable = 'off';
            app.AutoCheckBox.Text = 'Auto';
            app.SelectframeanglesfromatxtfileCheckBox = uicheckbox(app.uipanel8);
            app.SelectframeanglesfromatxtfileCheckBox.ValueChangedFcn = createCallbackFcn(app, @SelectframeanglesfromatxtfileCheckBoxFunction, true);
            app.SelectframeanglesfromatxtfileCheckBox.Text = 'Select frame angles from a .txt file';
            app.SubtractValuetoFramesLabel = uilabel(app.uipanel8);
            app.SubtractValuetoFramesLabel.Text = 'Subtract Value to Frames:';
            app.SubtractValuetoFramesEditField = uieditfield(app.uipanel8, 'text');
            app.SubtractValuetoFramesEditField.Value = '0';
            app.CheckSaturationButton = uibutton(app.uipanel8, 'push');
            app.CheckSaturationButton.ButtonPushedFcn = createCallbackFcn(app, @CheckSatuFcn, true);
            app.CheckSaturationButton.Enable = 'off';
            app.CheckSaturationButton.Text = 'Check Saturation';
            app.SatValueEditFieldLabel = uilabel(app.uipanel8);
            app.SatValueEditFieldLabel.Text = 'Sat. Value:';
            app.SatValueEditField = uieditfield(app.uipanel8, 'numeric');
            app.SatValueEditField.ValueDisplayFormat = '%1.0d';
            app.SatValueEditField.Value = 65535;
            app.Image = uiimage(app.figure1);
            app.Image.ImageSource = 'urv-bandera-color.png';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EDDataVisualizerAndFormatConverter_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @EDDataVisualizerAndFormatConverter_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end